classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TestingsignalfilteringTab       matlab.ui.container.Tab
        LoadfileButton                  matlab.ui.control.Button
        NoisereductionlevelSpinnerLabel  matlab.ui.control.Label
        NoisereductionlevelSpinner      matlab.ui.control.Spinner
        WindowingButtonGroup            matlab.ui.container.ButtonGroup
        HammingButton                   matlab.ui.control.RadioButton
        HannButton                      matlab.ui.control.RadioButton
        RectangleButton                 matlab.ui.control.RadioButton
        SelectpacketlengthSpinnerLabel  matlab.ui.control.Label
        SelectpacketlengthSpinner       matlab.ui.control.Spinner
        SamplingfrequencySpinnerLabel   matlab.ui.control.Label
        SamplingfrequencySpinner        matlab.ui.control.Spinner
        AddnoiseRSBSpinnerLabel         matlab.ui.control.Label
        AddnoiseRSBSpinner              matlab.ui.control.Spinner
        Spectrogram                     matlab.ui.control.UIAxes
        PlayPauseButton                 matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        BenchmarkingTab                 matlab.ui.container.Tab
        OtherplotsTab                   matlab.ui.container.Tab
    end


    properties (Access = private)
        signal          % line matrix holding loaded signal
        signal_noise    % Signal with noise
        signal_filt     % Signal filtered
        sigma_noise2    % Variance of noise
        window_type     % The window chosen by user
        playing = 0;    % If audio is played 1 if paused 2
        player;
        changed = 1;    % equals to 1 if settings changed
    end

    methods (Access = private)
    
        function window = window_methods(app)
            selectedButton = app.WindowingButtonGroup.SelectedObject.Text;
            pkt_len = app.SelectpacketlengthSpinner.Value;
            switch selectedButton
                case 'Hamming'
                    window = hamming(pkt_len).';
                case 'Hann'
                    window = hann(pkt_len).';
                case 'Rectangle'
                    window = ones(1, pkt_len);
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            data = struct2cell(load(strcat(path, file)));
            app.signal = data{1}.';
            app.changed = 1;
        end

        % Button pushed function: PlayPauseButton
        function PlayPauseButtonValueChanged(app, event)

            if app.changed == 1 
                if app.playing ~= 0
                    stop(app.player);
                    app.playing = 0;
                end
                %% Adding noise to signal
                rsb = app.AddnoiseRSBSpinner.Value;
                noise_level = app.NoisereductionlevelSpinner.Value;
                [app.signal_noise, app.sigma_noise2] = ajout_bruit(rsb, app.signal);
                
                %% Filtering the signal
                pkt_len = app.SelectpacketlengthSpinner.Value;
                window = window_methods(app);
                app.signal_filt = filter_signal(app.signal_noise, pkt_len, window, app.sigma_noise2, noise_level);
                app.signal_filt = app.signal_filt / max(abs(app.signal_filt));
                app.changed = 0;
                
                nfft = 2.^nextpow2(pkt_len);
                fe =  app.SamplingfrequencySpinner.Value;
                [S,F,T] = spectrogram(app.signal_filt, pkt_len, .5*pkt_len, nfft, fe, 'yaxis');
                
                imagesc(app.Spectrogram, T, F, log(abs(S)));
                
                app.Spectrogram.YLim = [0 (fe/2)];
                app.Spectrogram.YLabel.String = 'Frequency (Hz)';
                app.Spectrogram.XLim = [1 floor(length(app.signal_filt)/fe)];
                app.Spectrogram.XLabel.String = 'Time (s)';
                app.Spectrogram.YDir = 'normal';
                
                %% Starting player
                app.player = audioplayer(app.signal_filt, app.SamplingfrequencySpinner.Value);
            end
            
            if app.playing == 0                 
                play(app.player);
                app.playing = 1;
            elseif app.playing == 1
                app.playing = 2;
                pause(app.player);
            else
                app.playing = 1;
                resume(app.player);
            end
        end

        % Button pushed function: StopButton
        function StopButtonValueChanged(app, event)
%             value = app.StopButton.Value;
            if app.playing ~= 0
                stop(app.player);
                app.playing = 0;
            end
        end

        % Selection changed function: WindowingButtonGroup
        function WindowingButtonGroupSelectionChanged(app, event)
%             selectedButton = app.WindowingButtonGroup.SelectedObject;
%             disp(selectedButton);
            app.changed = 1;
        end

        % Value changed function: SelectpacketlengthSpinner
        function SelectpacketlengthSpinnerValueChanged(app, event)
%             value = app.SelectpacketlengthSpinner.Value;
%             disp(value);
            app.changed = 1;
        end

        % Value changed function: SamplingfrequencySpinner
        function SamplingfrequencySpinnerValueChanged(app, event)
%             value = app.SamplingfrequencySpinner.Value;
%             disp(value);
            app.changed = 1;
        end

        % Value changed function: AddnoiseRSBSpinner
        function AddnoiseRSBSpinnerValueChanged(app, event)
%             value = app.AddnoiseRSBSpinner.Value;
%             disp(value);
            app.changed = 1;
        end

        % Value changed function: NoisereductionlevelSpinner
        function NoisereductionlevelSpinnerValueChanged(app, event)
%             value = app.NoisereductionlevelSpinner.Value;
            app.changed = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TestingsignalfilteringTab = uitab(app.TabGroup);
            app.TestingsignalfilteringTab.Title = 'Testing signal filtering';
            app.LoadfileButton = uibutton(app.TestingsignalfilteringTab, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Icon = 'Upload_Document-512.png';
            app.LoadfileButton.Text = 'Load file';
            app.NoisereductionlevelSpinnerLabel = uilabel(app.TestingsignalfilteringTab);
            app.NoisereductionlevelSpinnerLabel.Text = 'Noise reduction level';
            app.NoisereductionlevelSpinner = uispinner(app.TestingsignalfilteringTab);
            app.NoisereductionlevelSpinner.Step = 0.25;
            app.NoisereductionlevelSpinner.Limits = [0 Inf];
            app.NoisereductionlevelSpinner.ValueChangedFcn = createCallbackFcn(app, @NoisereductionlevelSpinnerValueChanged, true);
            app.NoisereductionlevelSpinner.Value = 3;
            app.WindowingButtonGroup = uibuttongroup(app.TestingsignalfilteringTab);
            app.WindowingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WindowingButtonGroupSelectionChanged, true);
            app.WindowingButtonGroup.Title = 'Windowing';
            app.HammingButton = uiradiobutton(app.WindowingButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.HammingButton.Value = true;
            app.HannButton = uiradiobutton(app.WindowingButtonGroup);
            app.HannButton.Text = 'Hann';
            app.RectangleButton = uiradiobutton(app.WindowingButtonGroup);
            app.RectangleButton.Text = 'Rectangle';
            app.SelectpacketlengthSpinnerLabel = uilabel(app.TestingsignalfilteringTab);
            app.SelectpacketlengthSpinnerLabel.Text = 'Select packet length';
            app.SelectpacketlengthSpinner = uispinner(app.TestingsignalfilteringTab);
            app.SelectpacketlengthSpinner.Step = 0.25;
            app.SelectpacketlengthSpinner.Limits = [0 Inf];
            app.SelectpacketlengthSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectpacketlengthSpinnerValueChanged, true);
            app.SelectpacketlengthSpinner.Value = 400;
            app.SamplingfrequencySpinnerLabel = uilabel(app.TestingsignalfilteringTab);
            app.SamplingfrequencySpinnerLabel.Text = 'Sampling frequency';
            app.SamplingfrequencySpinner = uispinner(app.TestingsignalfilteringTab);
            app.SamplingfrequencySpinner.Step = 0.25;
            app.SamplingfrequencySpinner.Limits = [0 Inf];
            app.SamplingfrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @SamplingfrequencySpinnerValueChanged, true);
            app.SamplingfrequencySpinner.Value = 8000;
            app.AddnoiseRSBSpinnerLabel = uilabel(app.TestingsignalfilteringTab);
            app.AddnoiseRSBSpinnerLabel.Text = 'Add noise (RSB)';
            app.AddnoiseRSBSpinner = uispinner(app.TestingsignalfilteringTab);
            app.AddnoiseRSBSpinner.Limits = [0 Inf];
            app.AddnoiseRSBSpinner.ValueChangedFcn = createCallbackFcn(app, @AddnoiseRSBSpinnerValueChanged, true);
            app.AddnoiseRSBSpinner.Value = 10;
            app.Spectrogram = uiaxes(app.TestingsignalfilteringTab);
            title(app.Spectrogram, 'Spectrogram of filtered signal')
            xlabel(app.Spectrogram, 'Time (s)')
            ylabel(app.Spectrogram, 'Frequencies Hz')
            app.PlayPauseButton = uibutton(app.TestingsignalfilteringTab, 'push');
            app.PlayPauseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPauseButtonValueChanged, true);
            app.PlayPauseButton.Icon = 'stop_play_triangle_music_video_audio_arrow_button-512.png';
            app.PlayPauseButton.Text = 'Play / Pause';
            app.StopButton = uibutton(app.TestingsignalfilteringTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Icon = 'Stop-512.png';
            app.StopButton.Text = 'Stop';
            app.BenchmarkingTab = uitab(app.TabGroup);
            app.BenchmarkingTab.Title = 'Benchmarking';
            app.OtherplotsTab = uitab(app.TabGroup);
            app.OtherplotsTab.Title = 'Other plots';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Interface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end